classdef GoodCopy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Save_Figure             matlab.ui.container.Tab
        Plots                   matlab.ui.container.Panel
        Plot_Select             matlab.ui.control.DropDown
        SelectPlotLabel         matlab.ui.control.Label
        Record_Size             matlab.ui.control.NumericEditField
        LabelNumericEditField3  matlab.ui.control.Label
        Stop_Record             matlab.ui.control.Button
        Save                    matlab.ui.control.Button
        Start_Record            matlab.ui.control.Button
        Message_Save            matlab.ui.control.TextArea
        PCG_Record              matlab.ui.control.UIAxes
        SCG_Record              matlab.ui.control.UIAxes
        ECG_Record              matlab.ui.control.UIAxes
        Tab4                    matlab.ui.container.Tab
        Message_Load            matlab.ui.control.TextArea
        Settings                matlab.ui.container.Panel
        LabelNumericEditField   matlab.ui.control.Label
        Curr_Display            matlab.ui.control.NumericEditField
        is_Segment              matlab.ui.control.DropDown
        Display_Type            matlab.ui.control.CheckBox
        Back_Series             matlab.ui.control.Button
        Next_Series             matlab.ui.control.Button
        Button_Load             matlab.ui.control.Button
        Results                 matlab.ui.container.Panel
        Label8                  matlab.ui.control.Label
        LVET_Var                matlab.ui.control.NumericEditField
        ISR_Var                 matlab.ui.control.NumericEditField
        Label5                  matlab.ui.control.Label
        ISR_Ave                 matlab.ui.control.NumericEditField
        Label6                  matlab.ui.control.Label
        PEP_Var                 matlab.ui.control.NumericEditField
        Label3                  matlab.ui.control.Label
        PEP_Ave                 matlab.ui.control.NumericEditField
        Label4                  matlab.ui.control.Label
        ICT_Ave                 matlab.ui.control.NumericEditField
        Label2                  matlab.ui.control.Label
        ICT_Var                 matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        LabelNumericEditField2  matlab.ui.control.Label
        LVET_Ave                matlab.ui.control.NumericEditField
        SCG_plot                matlab.ui.control.UIAxes
        ECG_plot                matlab.ui.control.UIAxes
    end


    properties (Access = public)
        
        % File opening
        file; % Current file 
        path; % Current file path
        
        % Processing variables
        fs_sys = 1000; % Sampling frequency [Hz]
        fc_HP = 10; % Cutoff frequency for HP filter [Hz]
        fc_LP_ECG = 100; % Cutoff frequency for LP filter (ECG) [Hz]
        fc_LP_SCG = 20; % Cutoff frequency for KP filter (SCG) [Hz]
      
        % Data
        ECG; % ECG Loading data
        raw_ECG;
        ECG_pos_global;
        SCG; % SCG loading data
        SCG_pos_global;
        raw_SCG;
        pos; % Loading position vector
        base = 100; % Offset
       
        % Save data
        curr_data; % n by 3 matrix for ECG, SCG and PCG data
        curr_on = 1; % Status of input
        num_points = 0; % Number of data point
        
        % Graph formmating
        max_range;
        max_val_ECG_global;
        min_val_ECG_global;
        max_val_SCG_global;
        min_val_SCG_global;
        
        % Display Variables
        curr_discrete_val = 1; % Slide number
        curr_continuous_val = 1; % Current index
        curr_display_type = 0; % Segmented or continuous
        increment = 100; % 
        
    end % Public Properities

    methods (Access = private)
    
    end % Private Methods


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button_Load
        function Button_LoadButtonPushed(app, event)
            
            app.Message_Load.Value = {''};
            
            % Open matlab folder
            [app.file, app.path] = uigetfile;
            
            if (isequal(app.file, 0) == 0) % User did not press cancel 
                try % Correct file format 
                    processData(app);  
                    graphSegment(app);
                catch
                    app.Message_Load.Value = {'The file at: '; '';  fullfile(app.path, app.file); ''; 'is not in the correct format. Please refer to instruction manual for specifications.'};
                end % Try catch
            end % if statement

        end

        % Button pushed function: Next_Series
        function Next_SeriesButtonPushed(app, event)
            
            if (app.curr_display_type == 0) % Discrete display
                
                if(app.curr_discrete_val < length(app.pos) - 2) % Prevent overflow
                    app.curr_discrete_val = app.curr_discrete_val + 1;
                end % if statement
                
            else % Continuous display
                
                if (app.curr_continuous_val + app.increment < length(app.SCG)) % Prevent overflow
                    app.curr_continuous_val = app.curr_continuous_val + app.increment;
                end % if statement
                
            end  % if statement
            
            graphSegment(app);
            
        end

        % Button pushed function: Back_Series
        function Back_SeriesButtonPushed(app, event)
            
            if (app.curr_display_type == 0) % Discrete display
                
                if(app.curr_discrete_val - 1 > 0) % Prevent underflow
                    app.curr_discrete_val = app.curr_discrete_val - 1;
                end % if statement
                
            else % Continuous display
                
                if (app.curr_continuous_val - app.increment > 1) % Prevent underflow
                    app.curr_continuous_val = app.curr_continuous_val - app.increment;
                end % if statement
                
            end  % if statement
            
            graphSegment(app);
            
        end

        % Value changed function: Display_Type
        function Display_TypeValueChanged(app, event)
            
            disp(app.Display_Type.Value);
            app.curr_display_type = mod(app.curr_display_type + 1, 2);  
            
            if (app.curr_display_type == 0) % Slide based display
                app.curr_continuous_val = 1; % Reset scrolling
                
                app.ECG_plot.XLim = [1 800];
                app.SCG_plot.XLim = [1 800];                
                
                app.LabelNumericEditField.Text = 'Slide:';

            else % Continuous Display
                
                % Set up plot axes
                plot(app.ECG_plot, app.ECG);
                plot(app.SCG_plot, app.SCG);
                app.SCG_plot.YLim = [app.min_val_SCG_global * 1.1 app.max_val_SCG_global * 1.1];        
                app.ECG_plot.YLim = [app.min_val_ECG_global * 1.1 app.max_val_ECG_global * 1.1];

                app.curr_discrete_val = 1; % Reset slide
                app.LabelNumericEditField.Text = ' Step:';
                
            end % if statement
            
            graphSegment(app);

        end

        % Value changed function: is_Segment
        function is_SegmentValueChanged(app, event)
            
            if (strcmp(app.is_Segment.Value, 'Segmented')) % Display entire signal
                
                app.Curr_Display.Enable = 'on';
                app.Display_Type.Enable = 'on';
                app.Next_Series.Enable = 'on';
                app.Back_Series.Enable = 'on';
                app.LabelNumericEditField.Enable = 'on';
                
            else % Segmented display
               
                app.Curr_Display.Enable = 'off';
                app.Display_Type.Enable = 'off';
                app.Next_Series.Enable = 'off';
                app.Back_Series.Enable = 'off';
                app.LabelNumericEditField.Enable = 'off';
                
            end % if statement
            
            graphSegment(app);
            
        end

        % Value changed function: Curr_Display
        function Curr_DisplayValueChanged(app, event)
            
            value = app.Curr_Display.Value;
            disp(app.curr_display_type);
            disp(value);
            
            if (app.curr_display_type == 0) % Slide based display
                if (value > length(app.pos) - 1) % Exceed maximum bound
                    app.curr_discrete_val = length(app.pos) - 2;
                elseif (value < 1) % Exceed minimum bound
                    app.curr_discrete_val = 1;
                else % Suitable
                    app.curr_discrete_val = value;
                end % if statement
                
            else % Continuous display
                
                if (value > 500) % Exceed maximum bound
                    app.increment = 500;
                elseif (value < 1) % Exceed minimum bound
                    app.increment = 1;
                else % Suitable 
                    app.increment = value;
                end % if statement 
                
            end % if statement 
            
            graphSegment(app);
            
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            [filename, pathname] = uiputfile('*.mat', 'Save Workspace Variables As');
            newfilename = fullfile(pathname, filename);
            data = app.curr_data;
            save(newfilename, 'data');
        end

        % Button pushed function: Start_Record
        function Start_RecordButtonPushed(app, event)

            skip = 0;
            
            curr_plot_ECG = plot(app.ECG_Record, [0 0]);
            curr_plot_SCG = plot(app.SCG_Record, [0 0]);
            curr_plot_PCG = plot(app.PCG_Record, [0 0]);     
                 
            app.ECG_Record.YLim = [1800 2200];
            app.SCG_Record.YLim = [2600 3000];
            app.PCG_Record.YLim = [3000 3400];
            app.ECG_Record.XLim = [0 1000];
            app.SCG_Record.XLim = [0 1000];
            app.PCG_Record.XLim = [0 1000];
            
            app.curr_on = 1;
            
            % Set up serial transmission
            s = serial('COM4');
            s.BaudRate = 250000;
            s.Terminator = 'CR';
            fopen(s);
                   
            try % error during SCI

                while(fread(s, 1, 'uchar') == 0)
                    disp('pause');
                end % Paused 
                
                test3 = fread(s, 1, 'uchar');
                test3 = fread(s, 1, 'uchar');
                test2 = fread(s, 1, 'uchar');
                test2 = fread(s, 1, 'uchar');
                test2 = fread(s, 1, 'uchar');
                
                for i = 1:1:999
                
                    curr(1, :) = fread(s, 2, 'uchar');
                    signal(i, 1) = curr(1, 1) * 255 + curr(1, 2);
                    curr(1, :) = fread(s, 2, 'uchar');
                    signal(i, 2) = curr(1, 1) * 255 + curr(1, 2);
                    curr(1, :) = fread(s, 2, 'uchar');
                    signal(i, 3) = curr(1, 1) * 255 + curr(1, 2);
        
                end % for loop
                
                % Collect and display results
                i = 999;
                
                while(app.curr_on ~= -1)
                    
                    i = i + 1;
                    curr(1, :) = fread(s, 2, 'uchar');
                    signal(i, 1) = curr(1, 1) * 255 + curr(1, 2);
                    curr(1, :) = fread(s, 2, 'uchar');
                    signal(i, 2) = curr(1, 1) * 255 + curr(1, 2);
                    curr(1, :) = fread(s, 2, 'uchar');
                    signal(i, 3) = curr(1, 1) * 255 + curr(1, 2);
                
                    skip = mod(skip + 1, 100);
                    
                    if(skip == 0)
                        
                        if(app.Plot_Select.Value == 'ECG Plot')
                            set(curr_plot_ECG, 'YData', signal(i - 999:i, 1));
                        elseif(app.Plot_Select.Value == 'SCG Plot')
                            set(curr_plot_SCG, 'YData', signal(i - 999:i, 2));
                        else
                            set(curr_plot_PCG, 'YData', signal(i - 999:i, 3));
                        end % if statement
                            
                        drawnow limitrate;
                    end % if statement
                    
                end % for loop

                % Close SCI 
                fclose(s);
                delete(s);
                clear s;
                
                app.curr_data = signal;
                
            catch % Close COM Port
                
                % Close SCI 
                fclose(s);
                delete(s);
                clear s;
                return;

            end % try-catch
                
        end

        % Button pushed function: Stop_Record
        function Stop_RecordButtonPushed(app, event)
            app.curr_on = -1; % Trigger flag  
        end

        % Value changed function: Plot_Select
        function Plot_SelectValueChanged(app, event)
            value = app.Plot_Select.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Save_Figure = uitab(app.TabGroup);
            app.Save_Figure.Title = 'Record Data';
            app.Plots = uipanel(app.Save_Figure);
            app.Plots.Title = ' ';
            app.ECG_Record = uiaxes(app.Plots);
            title(app.ECG_Record, 'ECG Recording')
            xlabel(app.ECG_Record, 'Index [n]')
            ylabel(app.ECG_Record, 'Amplitude [V]')
            app.SCG_Record = uiaxes(app.Plots);
            title(app.SCG_Record, 'SCG Recording')
            xlabel(app.SCG_Record, 'Index [n]')
            ylabel(app.SCG_Record, 'Amplitude [V]')
            app.PCG_Record = uiaxes(app.Plots);
            title(app.PCG_Record, 'PCG Recording')
            xlabel(app.PCG_Record, 'Index [n]')
            ylabel(app.PCG_Record, 'Amplitude [V]')
            app.Message_Save = uitextarea(app.Plots);
            app.Message_Save.Editable = 'off';
            app.Start_Record = uibutton(app.Plots, 'push');
            app.Start_Record.ButtonPushedFcn = createCallbackFcn(app, @Start_RecordButtonPushed, true);
            app.Start_Record.Text = 'Start';
            app.Save = uibutton(app.Plots, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Save Data';
            app.Stop_Record = uibutton(app.Plots, 'push');
            app.Stop_Record.ButtonPushedFcn = createCallbackFcn(app, @Stop_RecordButtonPushed, true);
            app.Stop_Record.Text = 'Stop';
            app.LabelNumericEditField3 = uilabel(app.Plots);
            app.LabelNumericEditField3.Text = 'Current Size [Data Points]';
            app.Record_Size = uieditfield(app.Plots, 'numeric');
            app.Record_Size.Editable = 'off';
            app.SelectPlotLabel = uilabel(app.Plots);
            app.SelectPlotLabel.Text = 'Select Plot:';
            app.Plot_Select = uidropdown(app.Plots);
            app.Plot_Select.Items = {'ECG Plot', 'SCG Plot', 'PCG Plot'};
            app.Plot_Select.ValueChangedFcn = createCallbackFcn(app, @Plot_SelectValueChanged, true);
            app.Plot_Select.Value = 'ECG Plot';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'SCG Analysis';
            app.ECG_plot = uiaxes(app.Tab4);
            title(app.ECG_plot, 'ECG Data')
            xlabel(app.ECG_plot, 'Index [n]')
            ylabel(app.ECG_plot, 'Amplitude [V]')
            app.SCG_plot = uiaxes(app.Tab4);
            title(app.SCG_plot, 'SCG Data')
            xlabel(app.SCG_plot, 'Index [N]')
            ylabel(app.SCG_plot, 'Amplitude [V]')
            app.Results = uipanel(app.Tab4);
            app.Results.Title = 'Results';
            app.LVET_Ave = uieditfield(app.Results, 'numeric');
            app.LVET_Ave.Editable = 'off';
            app.LabelNumericEditField2 = uilabel(app.Results);
            app.LabelNumericEditField2.Text = 'LVET (Ave)';
            app.Label = uilabel(app.Results);
            app.Label.Text = 'ICT (Var)';
            app.ICT_Var = uieditfield(app.Results, 'numeric');
            app.ICT_Var.Editable = 'off';
            app.Label2 = uilabel(app.Results);
            app.Label2.Text = 'ICT (Ave)';
            app.ICT_Ave = uieditfield(app.Results, 'numeric');
            app.ICT_Ave.Editable = 'off';
            app.Label4 = uilabel(app.Results);
            app.Label4.Text = 'PEP (Var)';
            app.PEP_Ave = uieditfield(app.Results, 'numeric');
            app.PEP_Ave.Editable = 'off';
            app.Label3 = uilabel(app.Results);
            app.Label3.Text = 'PEP (Ave)';
            app.PEP_Var = uieditfield(app.Results, 'numeric');
            app.PEP_Var.Editable = 'off';
            app.Label6 = uilabel(app.Results);
            app.Label6.Text = 'ISR (Var)';
            app.ISR_Ave = uieditfield(app.Results, 'numeric');
            app.ISR_Ave.Editable = 'off';
            app.Label5 = uilabel(app.Results);
            app.Label5.Text = 'ISR (Ave)';
            app.ISR_Var = uieditfield(app.Results, 'numeric');
            app.ISR_Var.Editable = 'off';
            app.LVET_Var = uieditfield(app.Results, 'numeric');
            app.LVET_Var.Editable = 'off';
            app.Label8 = uilabel(app.Results);
            app.Label8.Text = 'LVET (Var)';
            app.Settings = uipanel(app.Tab4);
            app.Settings.Title = 'Settings';
            app.Button_Load = uibutton(app.Settings, 'push');
            app.Button_Load.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadButtonPushed, true);
            app.Button_Load.Text = 'Load';
            app.Next_Series = uibutton(app.Settings, 'push');
            app.Next_Series.ButtonPushedFcn = createCallbackFcn(app, @Next_SeriesButtonPushed, true);
            app.Next_Series.Enable = 'off';
            app.Next_Series.Text = '→';
            app.Back_Series = uibutton(app.Settings, 'push');
            app.Back_Series.ButtonPushedFcn = createCallbackFcn(app, @Back_SeriesButtonPushed, true);
            app.Back_Series.Enable = 'off';
            app.Back_Series.Text = '←';
            app.Display_Type = uicheckbox(app.Settings);
            app.Display_Type.ValueChangedFcn = createCallbackFcn(app, @Display_TypeValueChanged, true);
            app.Display_Type.Enable = 'off';
            app.Display_Type.Text = 'Continuous';
            app.is_Segment = uidropdown(app.Settings);
            app.is_Segment.Items = {'Processed Data', 'Segmented', 'Raw Data'};
            app.is_Segment.ValueChangedFcn = createCallbackFcn(app, @is_SegmentValueChanged, true);
            app.is_Segment.Enable = 'off';
            app.is_Segment.Value = 'Processed Data';
            app.Curr_Display = uieditfield(app.Settings, 'numeric');
            app.Curr_Display.ValueChangedFcn = createCallbackFcn(app, @Curr_DisplayValueChanged, true);
            app.Curr_Display.Enable = 'off';
            app.LabelNumericEditField = uilabel(app.Settings);
            app.LabelNumericEditField.Enable = 'off';
            app.LabelNumericEditField.Text = 'Slide:';
            app.Message_Load = uitextarea(app.Tab4);
            app.Message_Load.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoodCopy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end